classdef GeoTiff_viewer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure               matlab.ui.Figure
		Menu_File              matlab.ui.container.Menu
		Menu_OpenFile          matlab.ui.container.Menu
		GridLayout             matlab.ui.container.GridLayout
		LeftPanel              matlab.ui.container.Panel
		EditField_ClipData     matlab.ui.control.NumericEditField
		ClipdataLabel          matlab.ui.control.Label
		DropDown_ColorPallete  matlab.ui.control.DropDown
		ColormapLabel          matlab.ui.control.Label
		DropDown_SelectMetric  matlab.ui.control.DropDown
		MetricLabel            matlab.ui.control.Label
		CheckBox_ClipData      matlab.ui.control.CheckBox
		RightPanel             matlab.ui.container.Panel
		UIAxes_PlotTiff        matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	
	properties (Access = private)
		m_fileName 
		m_metrics = []
		m_RR
		m_lidarNames = ["Hmax", "Hmean","Hmedian","Hp25","Hp75", "Hp95"...
			"PPR","DAM_z","BR_bellow_1","BR_1_2","BR_2_3","BR_above_3","BR_3_4","BR_4_5","BR_bellow_5","BR_5_20","BR_above_20" ...
			"Coeff_var_z", "Hkurt", "Hskew", "Hstd", "Hvar", "Shannon"]
	end
	
	methods (Access = private)
		
		function DrawGraph(app)
			[X, Y] = app.m_RR.worldGrid();

			value = app.DropDown_SelectMetric.Value;
			band = find(contains(app.m_lidarNames, value));
			cmap = app.DropDown_ColorPallete.Value;
			chosenRaster = app.m_metrics(:,:, band);
			clipPercentile = app.EditField_ClipData.Value;

			if (app.CheckBox_ClipData.Value == 1)
				% compute chosen percentile for clipping
				chosenPercentile = prctile(chosenRaster(:), clipPercentile);

				% clip raster data according to the chosen percentile
				chosenRaster(chosenRaster > chosenPercentile) = chosenPercentile;
			end

			title(app.UIAxes_PlotTiff, app.m_fileName,'Interpreter','none')
			alphaData = ones(size(X));
			alphaData(isnan(chosenRaster)) = 0;
			imagesc(app.UIAxes_PlotTiff, X(1,:), Y(:,1), chosenRaster, 'AlphaData', alphaData)
% 			surf(app.UIAxes_PlotTiff, X, Y, chosenRaster, 'AlphaData', alphaData,'EdgeAlpha', 0)
% 			daspect(app.UIAxes_PlotTiff, [1 1 0.001])
			xticks(app.UIAxes_PlotTiff,'auto')
			yticks(app.UIAxes_PlotTiff,'auto')
			set(app.UIAxes_PlotTiff,'YDir','normal') % spravne hodnoty na y osi
			colormap(app.UIAxes_PlotTiff, cmap)
			colorbar(app.UIAxes_PlotTiff)
			axis(app.UIAxes_PlotTiff, 'equal')
% 			view(app.UIAxes_PlotTiff,3)

		end
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Menu selected function: Menu_OpenFile
		function Menu_OpenFileSelected(app, event)
			[app.m_fileName, filePath] = uigetfile('*.tif', 'Select TIF file'); % .osm file path
			filePath = fullfile(filePath, app.m_fileName);

			[app.m_metrics, app.m_RR] = readgeoraster(filePath, 'OutputType', 'double');
			infoMetrics = georasterinfo(filePath);

			app.m_metrics = standardizeMissing(app.m_metrics, infoMetrics.MissingDataIndicator);
			app.m_metrics = flipud(app.m_metrics);
			app.m_RR.ColumnsStartFrom = 'south';

			app.DropDown_SelectMetric.Items = app.m_lidarNames;

			DrawGraph(app);

		end

		% Value changed function: DropDown_ColorPallete
		function DropDown_ColorPalleteValueChanged(app, event)
			if (isempty(app.m_metrics))
				return
			end
			
			DrawGraph(app);
		end

		% Value changed function: DropDown_SelectMetric
		function DropDown_SelectMetricValueChanged(app, event)
			if (isempty(app.m_metrics))
				return
			end
			
			DrawGraph(app);
			
		end

		% Value changed function: CheckBox_ClipData
		function CheckBox_ClipDataValueChanged(app, event)
			DrawGraph(app);
			
		end

		% Value changed function: EditField_ClipData
		function EditField_ClipDataValueChanged(app, event)
			DrawGraph(app);
			
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {685, 685};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {220, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.Menu_File = uimenu(app.UIFigure);
			app.Menu_File.Text = 'File';
			app.Menu_OpenFile = uimenu(app.Menu_File);
			app.Menu_OpenFile.MenuSelectedFcn = createCallbackFcn(app, @Menu_OpenFileSelected, true);
			app.Menu_OpenFile.Accelerator = 'o';
			app.Menu_OpenFile.Text = 'Open file';
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.CheckBox_ClipData = uicheckbox(app.LeftPanel);
			app.CheckBox_ClipData.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ClipDataValueChanged, true);
			app.CheckBox_ClipData.Text = '';
			app.MetricLabel = uilabel(app.LeftPanel);
			app.MetricLabel.Text = 'Metric:';
			app.DropDown_SelectMetric = uidropdown(app.LeftPanel);
			app.DropDown_SelectMetric.Items = {};
			app.DropDown_SelectMetric.ValueChangedFcn = createCallbackFcn(app, @DropDown_SelectMetricValueChanged, true);
			app.DropDown_SelectMetric.Value = {};
			app.ColormapLabel = uilabel(app.LeftPanel);
			app.ColormapLabel.Text = 'Colormap:';
			app.DropDown_ColorPallete = uidropdown(app.LeftPanel);
			app.DropDown_ColorPallete.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag', 'white'};
			app.DropDown_ColorPallete.ValueChangedFcn = createCallbackFcn(app, @DropDown_ColorPalleteValueChanged, true);
			app.DropDown_ColorPallete.Value = 'jet';
			app.ClipdataLabel = uilabel(app.LeftPanel);
			app.ClipdataLabel.Text = 'Clip data:';
			app.EditField_ClipData = uieditfield(app.LeftPanel, 'numeric');
			app.EditField_ClipData.Limits = [0 100];
			app.EditField_ClipData.ValueChangedFcn = createCallbackFcn(app, @EditField_ClipDataValueChanged, true);
			app.EditField_ClipData.Value = 97.5;
			app.RightPanel = uipanel(app.GridLayout);
			app.UIAxes_PlotTiff = uiaxes(app.RightPanel);
			title(app.UIAxes_PlotTiff, 'Title')
			xlabel(app.UIAxes_PlotTiff, 'X')
			ylabel(app.UIAxes_PlotTiff, 'Y')
			zlabel(app.UIAxes_PlotTiff, 'Z')
			app.UIAxes_PlotTiff.XTick = [];
			app.UIAxes_PlotTiff.YTick = [];
			app.UIAxes_PlotTiff.ZTick = [];
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = GeoTiff_viewer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end