classdef boxplot_viewer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure               matlab.ui.Figure
		FileMenu               matlab.ui.container.Menu
		OpenfileMenu           matlab.ui.container.Menu
		GridLayout             matlab.ui.container.GridLayout
		LeftPanel              matlab.ui.container.Panel
		CheckBox_Scatterplot   matlab.ui.control.CheckBox
		Label_LoadedFileName   matlab.ui.control.Label
		Label_FileName         matlab.ui.control.Label
		YaxissettingsPanel     matlab.ui.container.Panel
		Spinner_YaxisRounding  matlab.ui.control.Spinner
		RoundtoLabel           matlab.ui.control.Label
		Spinner_YaxisTicks     matlab.ui.control.Spinner
		TicksLabel             matlab.ui.control.Label
		DebugText              matlab.ui.control.TextArea
		DropDown_SelectMetric  matlab.ui.control.DropDown
		MetricLabel            matlab.ui.control.Label
		RightPanel             matlab.ui.container.Panel
		UIAxes_max             matlab.ui.control.UIAxes
		UIAxes_min             matlab.ui.control.UIAxes
		UIAxes_std             matlab.ui.control.UIAxes
		UIAxes_mean            matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = private)
		m_data % Description
		m_class
		m_lidarNames = ["Hmax", "Hmean","Hmedian","Hp25","Hp75", "Hp95"...
			"PPR","DAM_z","BR_bellow_1","BR_1_2","BR_2_3","BR_above_3","BR_3_4","BR_4_5","BR_bellow_5","BR_5_20","BR_above_20" ...
			"Coeff_var_z", "Hkurt", "Hskew", "Hstd", "Hvar"]
		m_lidarNames3_6 = ["Hmax", "Hmean","Hmedian","Hp25","Hp75", "Hp95"...
			"PPR","DAM_z","BR_bellow_1","BR_1_2","BR_2_3","BR_above_3","BR_3_4","BR_4_5","BR_bellow_5","BR_5_20","BR_above_20" ...
			"Coeff_var_z", "Hkurt", "Hskew", "Hstd", "Hvar", "Shannon"]
	end

	methods (Access = private)

		function results = redraw_graphs(app)
			roundTo = app.Spinner_YaxisRounding.Value;
			nTicks = app.Spinner_YaxisTicks.Value;
			value = app.DropDown_SelectMetric.Value;

			if (size(app.m_data,2) == 88)
				n = find(contains(app.m_lidarNames, value));
				nk = [22,28,31,31,11];
			else
				n = find(contains(app.m_lidarNames3_6, value));
				nk = [22,27,31,31,22];
			end

			% colors for scatter plot
			c = [repelem(["r","g","b","m","k"], nk)', zeros(size(app.m_data,1),1),  zeros(size(app.m_data,1),1)];
			c(c(:,1) =="r", :) = repmat([1 0 0], nk(1), 1);
			c(c(:,1) =="g", :) = repmat([0 1 0], nk(2), 1);
			c(c(:,1) =="b", :) = repmat([0 0 1], nk(3), 1);
			c(c(:,1) =="m", :) = repmat([1 0 1], nk(4), 1);
			c(c(:,1) =="k", :) = repmat([0 0 0], nk(5), 1);
			c = str2double(c);

			suffixes = ["_mean", "_std", "_min", "_max"];
			plots = {app.UIAxes_mean, app.UIAxes_std, app.UIAxes_min, app.UIAxes_max};

			for i = 1:4
% 				clf(plots{i})
				data = app.m_data(:, 4*(n-1) + i);
				means = grpstats(data, app.m_class,"mean");

				yticks(plots{i}, round(linspace(min(data), max(data), nTicks), roundTo))
				title(plots{i}, value + suffixes(i), 'interpreter', 'none')

				boxplot(plots{i}, data , app.m_class,...
					'Labels', {'91E0', '91F0','91G0','9110','Mono'},...
					'Colors','rgbmk')
				hold(plots{i}, "on")
				if (app.CheckBox_Scatterplot.Value == 1)
					scatter(plots{i}, app.m_class, data, 20, c, "filled");
				end
				scatter(plots{i}, [1 2 3 4]', means(1:4), 50, [1 0 0;0 1 0;0 0 1;1 0 1],...
					"filled","o","MarkerEdgeColor","k","LineWidth",2)
				scatter(plots{i}, 5, means(5), 50,[0 0 0],...
					"filled","o","MarkerEdgeColor",[0.5 0.5 0.5],"LineWidth",2)
				hold(plots{i}, "off")
% 				drawnow
			end

			results = true;
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			data = readmatrix("repMetrics\RM_bio+mono_3.6.csv");
			app.m_class = data(:, end);
			app.m_data = data(:, 1:end-1);

			app.Label_LoadedFileName.Text = "RM_bio+mono_3.6.csv";
			app.DropDown_SelectMetric.Items = app.m_lidarNames3_6;
			redraw_graphs(app);
		end

		% Value changed function: DropDown_SelectMetric
		function DropDown_SelectMetricValueChanged(app, event)
			redraw_graphs(app);

		end

		% Menu selected function: OpenfileMenu
		function OpenfileMenuSelected(app, event)
			[fileName, filePath] = uigetfile('*.csv', 'Select CSV file'); % .osm file path
			filePath = fullfile(filePath, fileName);

			data = readmatrix(filePath);
			app.m_class = data(:, end);
			app.m_data = data(:, 1:end-1);

			app.DebugText.Value = app.DebugText.Value + "File loaded";
			app.Label_LoadedFileName.Text = fileName;
			
			if (size(app.m_data, 2) == 88)
				app.DropDown_SelectMetric.Items = app.m_lidarNames;
			else
				app.DropDown_SelectMetric.Items = app.m_lidarNames3_6;
			end

			redraw_graphs(app);
		end

		% Value changed function: Spinner_YaxisTicks
		function Spinner_YaxisTicksValueChanged(app, event)
			redraw_graphs(app);

		end

		% Value changed function: Spinner_YaxisRounding
		function Spinner_YaxisRoundingValueChanged(app, event)
			redraw_graphs(app);

		end

		% Callback function: not associated with a component
		function ButtonGroup_GraphTypeSelectionChanged(app, event)
			selectedButton = app.ButtonGroup_GraphType.SelectedObject;

% 			redraw_graphs(app);
		end

		% Callback function: not associated with a component
		function ButtonGroup_GraphTypeButtonDown(app, event)
			
		end

		% Value changed function: CheckBox_Scatterplot
		function CheckBox_ScatterplotValueChanged(app, event)
			value = app.CheckBox_Scatterplot.Value;
			
			redraw_graphs(app);
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {731, 731};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {219, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.FileMenu = uimenu(app.UIFigure);
			app.FileMenu.Text = 'File';
			app.OpenfileMenu = uimenu(app.FileMenu);
			app.OpenfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenfileMenuSelected, true);
			app.OpenfileMenu.Accelerator = 'O';
			app.OpenfileMenu.Text = 'Open file';
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.MetricLabel = uilabel(app.LeftPanel);
			app.MetricLabel.Text = 'Metric:';
			app.DropDown_SelectMetric = uidropdown(app.LeftPanel);
			app.DropDown_SelectMetric.Items = {};
			app.DropDown_SelectMetric.ValueChangedFcn = createCallbackFcn(app, @DropDown_SelectMetricValueChanged, true);
			app.DropDown_SelectMetric.Value = {};
			app.DebugText = uitextarea(app.LeftPanel);
			app.YaxissettingsPanel = uipanel(app.LeftPanel);
			app.YaxissettingsPanel.Title = 'Y axis settings';
			app.TicksLabel = uilabel(app.YaxissettingsPanel);
			app.TicksLabel.Text = 'Ticks:';
			app.Spinner_YaxisTicks = uispinner(app.YaxissettingsPanel);
			app.Spinner_YaxisTicks.Limits = [1 100];
			app.Spinner_YaxisTicks.ValueChangedFcn = createCallbackFcn(app, @Spinner_YaxisTicksValueChanged, true);
			app.Spinner_YaxisTicks.Value = 9;
			app.RoundtoLabel = uilabel(app.YaxissettingsPanel);
			app.RoundtoLabel.Text = 'Round to:';
			app.Spinner_YaxisRounding = uispinner(app.YaxissettingsPanel);
			app.Spinner_YaxisRounding.Limits = [1 5];
			app.Spinner_YaxisRounding.ValueChangedFcn = createCallbackFcn(app, @Spinner_YaxisRoundingValueChanged, true);
			app.Spinner_YaxisRounding.Value = 3;
			app.Label_FileName = uilabel(app.LeftPanel);
			app.Label_FileName.Text = 'Loaded file:';
			app.Label_LoadedFileName = uilabel(app.LeftPanel);
			app.Label_LoadedFileName.Text = '';
			app.CheckBox_Scatterplot = uicheckbox(app.LeftPanel);
			app.CheckBox_Scatterplot.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ScatterplotValueChanged, true);
			app.CheckBox_Scatterplot.Text = 'Scatter plot';
			app.RightPanel = uipanel(app.GridLayout);
			app.UIAxes_mean = uiaxes(app.RightPanel);
			title(app.UIAxes_mean, 'Title')
			xlabel(app.UIAxes_mean, 'Class')
			ylabel(app.UIAxes_mean, 'Metric values')
			zlabel(app.UIAxes_mean, 'Z')
			app.UIAxes_mean.XTick = [];
			app.UIAxes_mean.XTickLabel = '';
			app.UIAxes_mean.YTick = [];
			app.UIAxes_std = uiaxes(app.RightPanel);
			title(app.UIAxes_std, 'Title')
			xlabel(app.UIAxes_std, 'Class')
			ylabel(app.UIAxes_std, 'Metric values')
			zlabel(app.UIAxes_std, 'Z')
			app.UIAxes_std.XTick = [];
			app.UIAxes_std.XTickLabel = '';
			app.UIAxes_std.YTick = [];
			app.UIAxes_min = uiaxes(app.RightPanel);
			title(app.UIAxes_min, 'Title')
			xlabel(app.UIAxes_min, 'Class')
			ylabel(app.UIAxes_min, 'Metric values')
			zlabel(app.UIAxes_min, 'Z')
			app.UIAxes_min.XTick = [];
			app.UIAxes_min.XTickLabel = '';
			app.UIAxes_min.YTick = [];
			app.UIAxes_max = uiaxes(app.RightPanel);
			title(app.UIAxes_max, 'Title')
			xlabel(app.UIAxes_max, 'Class')
			ylabel(app.UIAxes_max, 'Metric values')
			zlabel(app.UIAxes_max, 'Z')
			app.UIAxes_max.XTick = [];
			app.UIAxes_max.XTickLabel = '';
			app.UIAxes_max.YTick = [];
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = boxplot_viewer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end